classdef InerterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        InerterMassEditField            matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        InnerRadiiEditField             matlab.ui.control.NumericEditField
        InnerRadiimEditFieldLabel       matlab.ui.control.Label
        OuterRadiiEditField             matlab.ui.control.NumericEditField
        OuterRadiimEditFieldLabel       matlab.ui.control.Label
        numGearsEditField               matlab.ui.control.NumericEditField
        ofGearsEditFieldLabel           matlab.ui.control.Label
        CalculatedInertanceEditField_2  matlab.ui.control.NumericEditField
        CalculatedInertancekgEditFieldLabel_2  matlab.ui.control.Label
        TimesSlider                     matlab.ui.control.Slider
        TimesSliderLabel                matlab.ui.control.Label
        StartDataCollectionButton       matlab.ui.control.StateButton
        ExperimentalInertanceEditField  matlab.ui.control.NumericEditField
        ExperimentalInertanceLabel      matlab.ui.control.Label
        PerformInertanceCalculationButton  matlab.ui.control.Button
        ForcePlot                       matlab.ui.control.UIAxes
        AccPlot                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        forceDataArray % Array storing force data from load cell
        accDataArray % Array storing accelerometer data
        accTimeArray % Array storing time data from accelerometer/daq
        forceTimeArray % Array storing time data from arduino load cell
        F_exp % Experimentally determined F value
        a_exp % Experimentally determined a value
        b_exp % Experimental b value
        b_theo % Theoretical b value
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PerformInertanceCalculationButton
        function CalculateInertance(app, event)
            % Pulling current data displayed from the graphs

            % Only generates experimental results if data is present
            if ~isempty(app.forceDataArray) && ~isempty(app.accDataArray)

                % Frequency Domain Analysis
                forceFD = fft(app.forceDataArray); % Force data in frequency domain
                accFD = fft(app.accDataArray);     % Acceleration data in frequency domain
                app.F_exp = max(abs(forceFD));              % Experimentally determined F value
                app.a_exp = max(abs(accFD));                % Experimentally determined a value

                % Calculating experimental inertance value
                app.b_exp = real(app.F_exp/app.a_exp);
            else
                app.b_exp = 0;
            end

            if app.InnerRadiiEditField.Value ~= 0

                % Calculating theoretical inertance value
                R = app.OuterRadiiEditField.Value;        % Outer gear radius (m)
                r = app.InnerRadiiEditField.Value;        % Inner gear radius (m)
                m = app.InerterMassEditField.Value;       % Mass of inerter (kg)
                n = app.numGearsEditField.Value;          % Number of gears in train
                app.b_theo = (R^2/r^2)^n * m;

            else

                app.b_theo = 0;

            end


            % Displaying inertance results to GUI
            app.ExperimentalInertanceEditField.Value = app.b_exp;
            app.CalculatedInertanceEditField_2.Value = app.b_theo;
        end

        % Value changed function: StartDataCollectionButton
        function StartDataCollectionButtonValueChanged(app, event)


            serialObj = serialport("COM7", 115200); % Serial port and baud rate from arduino script
            configureTerminator(serialObj, "CR/LF"); % Terminator is a line return
            flush(serialObj); % Clear serial data

            d = daq("ni"); % Create daq object
            ch = addinput(d, "cDAQ1Mod1", "ai0", "Accelerometer"); % Add input for accelerometer
            d.Rate = 2048; % Data collection rate of accelerometer
            ch.Sensitivity = 0.00999; % See accelerometer paper in box

            app.forceDataArray = [];
            app.forceTimeArray = [];
            app.accDataArray = [];
            app.accTimeArray = [];


            start(d, "duration", app.TimesSlider.Value);


            while d.Running

                app.forceDataArray = [app.forceDataArray str2double(readline(serialObj))];
                app.forceTimeArray = [app.forceTimeArray str2double(readline(serialObj))];

                [data, timestamps, ~] = read(d, d.ScansAvailableFcnCount, "OutputFormat", "Matrix");

                app.accTimeArray = [app.accTimeArray; timestamps];
                app.accDataArray = [app.accDataArray; data];

                plot(app.ForcePlot, app.forceTimeArray ,app.forceDataArray, 'k-');
                plot(app.AccPlot, app.accTimeArray, app.accDataArray, 'k-');

            end


        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)

            if isfile(app.FilenameEditField.Value)

                fig = uifigure;
                msg = "Saving these changes will overwrite previous changes.";
                title = "Confirm Save";

                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Overwrite","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);

                if strcmp("Cancel", selection)
                    return;
                end
            end

            if(size(app.forceDataArray, 2) <  size(app.accDataArray,1))

                T_Data = zeros(size(app.accDataArray,1),8);

            else

                T_Data = zeros(size(app.forceDataArray,1),8);

            end

            T_Data(1:size(app.forceDataArray, 2), 1) = app.forceDataArray';
            T_Data(1:size(app.forceDataArray, 2), 2) = app.forceTimeArray';
            T_Data(1:size(app.accDataArray, 1), 3) = app.accDataArray;
            T_Data(1:size(app.accDataArray, 1), 4) = app.accTimeArray;
            T_Data(1, 5) = app.F_exp';
            T_Data(1, 6) = app.a_exp';
            T_Data(1, 7) = app.b_theo';
            T_Data(1, 8) = app.b_exp';

           T = array2table(T_Data,'VariableNames', ...
               {'Force', 'Force Time', ...
               'Acceleration', 'Acceleration Time',...
               'Force FFT', 'Acceleration FFT', 'Theoretical B value','Experimental B value'});
           

            writetable(T, app.FilenameEditField.Value)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccPlot = uiaxes(app.UIFigure);
            title(app.AccPlot, 'Acceleration Data')
            xlabel(app.AccPlot, 'Time (s)')
            ylabel(app.AccPlot, 'Acceleration (m/s^2)')
            zlabel(app.AccPlot, 'Z')
            app.ForcePlot = uiaxes(app.UIFigure);
            title(app.ForcePlot, 'Force Data')
            xlabel(app.ForcePlot, 'Time (s)')
            ylabel(app.ForcePlot, 'Force (N)')
            zlabel(app.ForcePlot, 'Z')
            app.PerformInertanceCalculationButton = uibutton(app.UIFigure, 'push');
            app.PerformInertanceCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInertance, true);
            app.PerformInertanceCalculationButton.Text = {'Perform'; 'Inertance'; 'Calculation'};
            app.ExperimentalInertanceLabel = uilabel(app.UIFigure);
            app.ExperimentalInertanceLabel.Text = {'Experimental'; 'Inertance (kg)'};
            app.ExperimentalInertanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'state');
            app.StartDataCollectionButton.ValueChangedFcn = createCallbackFcn(app, @StartDataCollectionButtonValueChanged, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [1 10];
            app.TimesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.TimesSlider.Value = 1;
            app.CalculatedInertancekgEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CalculatedInertancekgEditFieldLabel_2.Text = {'Calculated'; 'Inertance (kg)'};
            app.CalculatedInertanceEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CalculatedInertanceEditField_2.Editable = 'off';
            app.ofGearsEditFieldLabel = uilabel(app.UIFigure);
            app.ofGearsEditFieldLabel.Text = '# of Gears';
            app.numGearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiimEditFieldLabel.Text = 'Outer Radii (m)';
            app.OuterRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiimEditFieldLabel.Text = 'Inner Radii (m)';
            app.InnerRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.InerterMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InerterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end